classdef particle_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ReplotButton                  matlab.ui.control.Button
        zaxisDropDown                 matlab.ui.control.DropDown
        zaxisDropDownLabel            matlab.ui.control.Label
        yaxisDropDown                 matlab.ui.control.DropDown
        yaxisDropDownLabel            matlab.ui.control.Label
        xaxisDropDown                 matlab.ui.control.DropDown
        xaxisDropDownLabel            matlab.ui.control.Label
        AbhinavSinhaLabel             matlab.ui.control.Label
        ExportDataButton              matlab.ui.control.Button
        PresetsDropDown               matlab.ui.control.DropDown
        PresetsDropDownLabel          matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ParticleIntegratorLabel       matlab.ui.control.Label
        vxEditField                   matlab.ui.control.NumericEditField
        vxEditFieldLabel              matlab.ui.control.Label
        vyEditField                   matlab.ui.control.NumericEditField
        vyEditFieldLabel              matlab.ui.control.Label
        vzEditField                   matlab.ui.control.NumericEditField
        vzEditFieldLabel              matlab.ui.control.Label
        InitialvelocityLabel          matlab.ui.control.Label
        InitialpositionLabel          matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        timestepEditField             matlab.ui.control.NumericEditField
        timestepEditFieldLabel        matlab.ui.control.Label
        SimulationPropertiesLabel     matlab.ui.control.Label
        ParticlePropertiesLabel       matlab.ui.control.Label
        EnvironmentPropertiesLabel    matlab.ui.control.Label
        MasskgEditField               matlab.ui.control.NumericEditField
        MasskgEditFieldLabel          matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        timesEditField                matlab.ui.control.NumericEditField
        timesEditFieldLabel           matlab.ui.control.Label
        ChargeCEditField              matlab.ui.control.NumericEditField
        ChargeCEditFieldLabel         matlab.ui.control.Label
        MagneticFieldzEditField       matlab.ui.control.EditField
        MagneticFieldzEditFieldLabel  matlab.ui.control.Label
        MagneticFieldyEditField       matlab.ui.control.EditField
        MagneticFieldyEditFieldLabel  matlab.ui.control.Label
        MagneticFieldxEditField       matlab.ui.control.EditField
        MagneticFieldxEditFieldLabel  matlab.ui.control.Label
        ElectricFieldzEditField       matlab.ui.control.EditField
        ElectricFieldzEditFieldLabel  matlab.ui.control.Label
        ElectricFieldyEditField       matlab.ui.control.EditField
        ElectricFieldyEditFieldLabel  matlab.ui.control.Label
        ElectricFieldxEditField       matlab.ui.control.EditField
        ElectricFieldxEditFieldLabel  matlab.ui.control.Label
        speed                         matlab.ui.control.UIAxes
        Trajectory                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t = 0 % Global time variable
        data % Description
    end
    
    methods (Access = private)
        
        function Exo = Ex(app,x,y,z,t)
            Ex = str2func(strcat('@(x,y,z,t)',app.ElectricFieldxEditField.Value));
            Exo = Ex(x,y,z,t);
        end
        function Eyo = Ey(app,x,y,z,t)
            Ey = str2func(strcat('@(x,y,z,t)',app.ElectricFieldyEditField.Value));
            Eyo = Ey(x,y,z,t);
        end
        function Ezo = Ez(app,x,y,z,t)
            Ez = str2func(strcat('@(x,y,z,t)',app.ElectricFieldzEditField.Value));
            Ezo = Ez(x,y,z,t);
        end
        function Bxo = Bx(app,x,y,z,t)
            Bx = str2func(['@(x,y,z,t)' app.MagneticFieldxEditField.Value]);
            Bxo = Bx(x,y,z,t);
        end
        function Byo = By(app,x,y,z,t)
            By = str2func(['@(x,y,z,t)' app.MagneticFieldyEditField.Value]);
            Byo = By(x,y,z,t);
        end
        function Bzo = Bz(app,x,y,z,t)
            Bz = str2func(['@(x,y,z,t)' app.MagneticFieldzEditField.Value]);
            Bzo = Bz(x,y,z,t);
        end
        
        function Efield = E(app, pos, t)
            Efield = [app.Ex(pos(1), pos(2), pos(3), t) app.Ey(pos(1), pos(2), pos(3), t) app.Ez(pos(1), pos(2), pos(3), t)];
        end
        function Bfield = B(app, pos, t)
            Bfield = [app.Bx(pos(1), pos(2), pos(3), t) app.By(pos(1), pos(2), pos(3), t) app.Bz(pos(1), pos(2), pos(3), t)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %for progress bar
            app.StartButton.Text = "Processing";
            app.StartButton.IconAlignment = "bottom";
            wbar = permute(repmat(app.StartButton.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.StartButton.Icon = wbar;
            
            app.t = 0;
            position = [app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            velocity = [app.vxEditField.Value app.vyEditField.Value app.vzEditField.Value];
            myparticle = particle(position,velocity, app.ChargeCEditField.Value, app.MasskgEditField.Value, app.timestepEditField.Value);
            myparticle = myparticle.reset();
            n = int32(app.timesEditField.Value/app.timestepEditField.Value);
            time_array = zeros(n,1);
            position_array = zeros(n, 3);
            velocity_array = zeros(n, 3);
            position_array(1,:) = myparticle.position;
            velocity_array(1,:) = myparticle.velocity;
            time_array(1) = app.t;
            i = 2;
            while app.t < app.timesEditField.Value
                myparticle = myparticle.borispush(app.E(myparticle.position, app.t), app.B(myparticle.position, app.t));
                position_array(i,:) = myparticle.position;
                velocity_array(i,:) = myparticle.velocity;
                app.t = app.t + app.timestepEditField.Value;
                time_array(i) = app.t;
                if mod(i,2000) == 0
                currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
                app.StartButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.StartButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.StartButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                drawnow
                end
                i = i + 1;
                
                
            end
            time = time_array;
            x = position_array(:,1);
            y = position_array(:,2);
            z = position_array(:,3);
            vx = velocity_array(:,1);
            vy = velocity_array(:,2);
            vz = velocity_array(:,3);
            app.data = table(time,x,y,z,vx,vy,vz);
            plotarray = table2array(app.data);
            if app.zaxisDropDown.Value ~= '8'
                plot3(app.Trajectory, plotarray(:,str2double(app.xaxisDropDown.Value)),plotarray(:,str2double(app.yaxisDropDown.Value)),plotarray(:,str2double(app.zaxisDropDown.Value)), 'r-')
            else
                plot(app.Trajectory, plotarray(:,str2double(app.xaxisDropDown.Value)),plotarray(:,str2double(app.yaxisDropDown.Value)))
                
            end
            %plot3(app.Trajectory, position_array(:,1), position_array(:,2), position_array(:,3), 'r-')
            plot(app.speed, time_array , vecnorm(velocity_array,2,2), 'r-')


            app.StartButton.Icon = '';
            app.StartButton.Text = "Start";
        end

        % Value changed function: PresetsDropDown
        function PresetsDropDownValueChanged(app, event)
            value = app.PresetsDropDown.Value;
            if value == '2'
                app.ElectricFieldxEditField.Value = '0';
                app.ElectricFieldyEditField.Value = '0';
                app.ElectricFieldzEditField.Value = '0';
                app.MagneticFieldxEditField.Value = '2';
                app.MagneticFieldyEditField.Value = '0';
                app.MagneticFieldzEditField.Value = '0';
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
                app.zEditField.Value = 0;
                app.vxEditField.Value = 0;
                app.vyEditField.Value = 2;
                app.vzEditField.Value = 0;
                app.ChargeCEditField.Value = 1;
                app.MasskgEditField.Value = 1;
            end
            if value == '3'
                app.ElectricFieldxEditField.Value = '0';
                app.ElectricFieldyEditField.Value = '0.05';
                app.ElectricFieldzEditField.Value = '0';
                app.MagneticFieldxEditField.Value = '0';
                app.MagneticFieldyEditField.Value = '0';
                app.MagneticFieldzEditField.Value = '1';
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
                app.zEditField.Value = 0;
                app.vxEditField.Value = 1;
                app.vyEditField.Value = 0;
                app.vzEditField.Value = 0;
                app.ChargeCEditField.Value = 1;
                app.MasskgEditField.Value = 1;
            end
            if value == '4'
                app.ElectricFieldxEditField.Value = '0';
                app.ElectricFieldyEditField.Value = '0';
                app.ElectricFieldzEditField.Value = '0';
                app.MagneticFieldxEditField.Value = '0';
                app.MagneticFieldyEditField.Value = '0';
                app.MagneticFieldzEditField.Value = '0.01*y+1';
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
                app.zEditField.Value = 0;
                app.vxEditField.Value = 1;
                app.vyEditField.Value = 0;
                app.vzEditField.Value = 0;
                app.ChargeCEditField.Value = 1;
                app.MasskgEditField.Value = 1;
            end
            if value == '5'
                app.ElectricFieldxEditField.Value = '0';
                app.ElectricFieldyEditField.Value = '0';
                app.ElectricFieldzEditField.Value = '0';
                app.MagneticFieldxEditField.Value = '-sin(z/20*pi)/40*pi*x';
                app.MagneticFieldyEditField.Value = '-sin(z/20*pi)/40*pi*(y+1)';
                app.MagneticFieldzEditField.Value = '2-cos(z/20*pi)';
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
                app.zEditField.Value = 0;
                app.vxEditField.Value = 0.6;
                app.vyEditField.Value = 0;
                app.vzEditField.Value = 0.6;
                app.ChargeCEditField.Value = 1;
                app.MasskgEditField.Value = 1;
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            filter = {'*.txt'; '*.dat'; '*.csv' ;'*.xls';'*.xlsm' ; '*.xlsx'};
            [file, path, idx] = uiputfile(filter, 'Export Data');
            if file ~= 0
                writetable(app.data, [path '/' file])
            end
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            if isempty(app.data) == 1
                f = errordlg("Please press Start button to aquire data first", "No data to replot");
            else
                plotarray = table2array(app.data);
                if app.zaxisDropDown.Value ~= '8'
                    plot3(app.Trajectory, plotarray(:,str2double(app.xaxisDropDown.Value)),plotarray(:,str2double(app.yaxisDropDown.Value)),plotarray(:,str2double(app.zaxisDropDown.Value)), 'r-')
                else
                    plot(app.Trajectory, plotarray(:,str2double(app.xaxisDropDown.Value)),plotarray(:,str2double(app.yaxisDropDown.Value)))
                    view(app.Trajectory, 0,90)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Trajectory = uiaxes(app.UIFigure);
            title(app.Trajectory, 'Graph')
            xlabel(app.Trajectory, 'X')
            ylabel(app.Trajectory, 'Y')
            zlabel(app.Trajectory, 'Z')
            app.speed = uiaxes(app.UIFigure);
            title(app.speed, 'speed vs time')
            xlabel(app.speed, 'time')
            ylabel(app.speed, 'v')
            zlabel(app.speed, 'Z')
            app.ElectricFieldxEditFieldLabel = uilabel(app.UIFigure);
            app.ElectricFieldxEditFieldLabel.Text = 'Electric Field (x)';
            app.ElectricFieldxEditField = uieditfield(app.UIFigure, 'text');
            app.ElectricFieldxEditField.Value = '0';
            app.ElectricFieldyEditFieldLabel = uilabel(app.UIFigure);
            app.ElectricFieldyEditFieldLabel.Text = 'Electric Field (y)';
            app.ElectricFieldyEditField = uieditfield(app.UIFigure, 'text');
            app.ElectricFieldyEditField.Value = '0';
            app.ElectricFieldzEditFieldLabel = uilabel(app.UIFigure);
            app.ElectricFieldzEditFieldLabel.Text = 'Electric Field (z)';
            app.ElectricFieldzEditField = uieditfield(app.UIFigure, 'text');
            app.ElectricFieldzEditField.Value = '0';
            app.MagneticFieldxEditFieldLabel = uilabel(app.UIFigure);
            app.MagneticFieldxEditFieldLabel.Text = 'Magnetic Field (x)';
            app.MagneticFieldxEditField = uieditfield(app.UIFigure, 'text');
            app.MagneticFieldxEditField.Value = '0';
            app.MagneticFieldyEditFieldLabel = uilabel(app.UIFigure);
            app.MagneticFieldyEditFieldLabel.Text = 'Magnetic Field (y)';
            app.MagneticFieldyEditField = uieditfield(app.UIFigure, 'text');
            app.MagneticFieldyEditField.Value = '0';
            app.MagneticFieldzEditFieldLabel = uilabel(app.UIFigure);
            app.MagneticFieldzEditFieldLabel.Text = 'Magnetic Field (z)';
            app.MagneticFieldzEditField = uieditfield(app.UIFigure, 'text');
            app.MagneticFieldzEditField.Value = '0';
            app.ChargeCEditFieldLabel = uilabel(app.UIFigure);
            app.ChargeCEditFieldLabel.Text = 'Charge (C)';
            app.ChargeCEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChargeCEditField.Value = 1;
            app.timesEditFieldLabel = uilabel(app.UIFigure);
            app.timesEditFieldLabel.Text = 'time (s)';
            app.timesEditField = uieditfield(app.UIFigure, 'numeric');
            app.timesEditField.Value = 50;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Value = 1;
            app.EnvironmentPropertiesLabel = uilabel(app.UIFigure);
            app.EnvironmentPropertiesLabel.Text = 'Environment Properties';
            app.ParticlePropertiesLabel = uilabel(app.UIFigure);
            app.ParticlePropertiesLabel.Text = 'Particle Properties';
            app.SimulationPropertiesLabel = uilabel(app.UIFigure);
            app.SimulationPropertiesLabel.Text = 'Simulation Properties';
            app.timestepEditFieldLabel = uilabel(app.UIFigure);
            app.timestepEditFieldLabel.Text = 'time step';
            app.timestepEditField = uieditfield(app.UIFigure, 'numeric');
            app.timestepEditField.Value = 0.1;
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialpositionLabel = uilabel(app.UIFigure);
            app.InitialpositionLabel.Text = 'Initial position';
            app.InitialvelocityLabel = uilabel(app.UIFigure);
            app.InitialvelocityLabel.Text = 'Initial velocity';
            app.vzEditFieldLabel = uilabel(app.UIFigure);
            app.vzEditFieldLabel.Text = 'vz';
            app.vzEditField = uieditfield(app.UIFigure, 'numeric');
            app.vyEditFieldLabel = uilabel(app.UIFigure);
            app.vyEditFieldLabel.Text = 'vy';
            app.vyEditField = uieditfield(app.UIFigure, 'numeric');
            app.vxEditFieldLabel = uilabel(app.UIFigure);
            app.vxEditFieldLabel.Text = 'vx';
            app.vxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParticleIntegratorLabel = uilabel(app.UIFigure);
            app.ParticleIntegratorLabel.Text = 'Particle Integrator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'html';
            app.Label.Text = 'This Simulator uses Boris solver to compute the trajectory in defined electric and magnetic field. The fields can be defined as constants or functions of position (x,y,z) and time (t) <br><br>For Example:<br>B_x = -sin(z/20*pi)/40*pi*x <br>B_y =  -sin(z/20*pi)/40*pi*(y+1) <br>B_z =  2-cos(z/20*pi)';
            app.PresetsDropDownLabel = uilabel(app.UIFigure);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.UIFigure);
            app.PresetsDropDown.Items = {'Custom', 'Uniform B', 'E corss B', 'Grad B', 'Magnetic Mirrors'};
            app.PresetsDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.PresetsDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetsDropDownValueChanged, true);
            app.PresetsDropDown.Value = '1';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.AbhinavSinhaLabel = uilabel(app.UIFigure);
            app.AbhinavSinhaLabel.Text = 'Abhinav Sinha';
            app.xaxisDropDownLabel = uilabel(app.UIFigure);
            app.xaxisDropDownLabel.Text = 'x-axis';
            app.xaxisDropDown = uidropdown(app.UIFigure);
            app.xaxisDropDown.Items = {'t', 'x', 'y', 'z', 'vx', 'vy', 'vz'};
            app.xaxisDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.xaxisDropDown.Value = '2';
            app.yaxisDropDownLabel = uilabel(app.UIFigure);
            app.yaxisDropDownLabel.Text = 'y-axis';
            app.yaxisDropDown = uidropdown(app.UIFigure);
            app.yaxisDropDown.Items = {'t', 'x', 'y', 'z', 'vx', 'vy', 'vz'};
            app.yaxisDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.yaxisDropDown.Value = '3';
            app.zaxisDropDownLabel = uilabel(app.UIFigure);
            app.zaxisDropDownLabel.Text = 'z-axis';
            app.zaxisDropDown = uidropdown(app.UIFigure);
            app.zaxisDropDown.Items = {'t', 'x', 'y', 'z', 'vx', 'vy', 'vz', 'None'};
            app.zaxisDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.zaxisDropDown.Value = '4';
            app.ReplotButton = uibutton(app.UIFigure, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Re plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particle_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end